classdef ECG_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ECGstateTextArea       matlab.ui.control.TextArea
        ECGstateTextAreaLabel  matlab.ui.control.Label
        HRTextArea             matlab.ui.control.TextArea
        HRTextAreaLabel        matlab.ui.control.Label
        StartpauseButton       matlab.ui.control.StateButton
        UIAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = public)
          i = 1; % index for iterations on frmaes
          ECG_data = evalin('base','ECG'); 
          SVM = evalin('base','finetree');
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StartpauseButton
        function StartpauseButtonValueChanged(app, event)
        % uppon pressing the start button the live ECG processing will
        % commence. when pressing the button again the plot will freeze and
        % all processing will stop. 
            
            value = app.StartpauseButton.Value; % # of presses on the start button
            t = 0:1/300:(length(app.ECG_data)-1)/300;
            while (mod(value,2) == 1 && app.i<=(length(app.ECG_data)-1500)) %if odd, i.e 'Start'

                frame = app.ECG_data(app.i:1501+app.i); % 5 seconds frames of the ECG
                t_frame = t(app.i:1501+app.i); % matching time vector
                % ----------------------------------------------------
                % The following function - 'GUI_features' calculates the 
                % features needed for SVM model classification of
                % AF/normal condition. one of the features is Heart rated
                % calculated using Pan Thompking arlgorithm for QRS
                % detection
                %
                [Features_table,HR,ECG_R,time_R,clean] = GUI_features(frame,300,t_frame(1));
                %-------------------------------------------------------
                % Classifying for AF/normal ECG
                ECG_state = app.SVM.predictFcn(Features_table);
                % updating the ECG state text and plotting the ECG
                % if the ECG is normal the plot will be blue and if its AF
                % the plot will be red
                if ECG_state{1} == 'N'
                    app.ECGstateTextArea.Value = 'Normal'; 
                    plot(app.UIAxes,t_frame,clean,'b')
                else
                    app.ECGstateTextArea.Value = 'AF';
                    plot(app.UIAxes,t_frame,clean,'r')
                end
                ylim(app.UIAxes,[-450 1200]);
                xlim(app.UIAxes,[(t_frame(1)+1) (t_frame(end)-1)]);
                %-------------------------------------------------------
                % plotting R wave detections:
                hold (app.UIAxes,'on');
                plot(app.UIAxes,time_R,ECG_R,'g*','LineWidth',2)
                hold (app.UIAxes,'off');
                %-------------------------------------------------------
                % updating HR text box
                app.HRTextArea.Value = num2str(HR);
                app.i = app.i + 1;
                pause(0.01)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Live ECG analasys')
            xlabel(app.UIAxes, 'time [sec]')
            ylabel(app.UIAxes, 'Voltage [mV]')
            zlabel(app.UIAxes, 'Z')
            app.StartpauseButton = uibutton(app.UIFigure, 'state');
            app.StartpauseButton.ValueChangedFcn = createCallbackFcn(app, @StartpauseButtonValueChanged, true);
            app.StartpauseButton.Text = 'Start / pause';
            app.HRTextAreaLabel = uilabel(app.UIFigure);
            app.HRTextAreaLabel.Text = 'HR';
            app.HRTextArea = uitextarea(app.UIFigure);
            app.ECGstateTextAreaLabel = uilabel(app.UIFigure);
            app.ECGstateTextAreaLabel.Text = 'ECG state';
            app.ECGstateTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECG_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end